classdef ImgFusionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        LoadImagesButton  matlab.ui.control.Button
        UIAxes2           matlab.ui.control.UIAxes
        UIAxes3           matlab.ui.control.UIAxes
        AlphaSliderLabel  matlab.ui.control.Label
        AlphaSlider       matlab.ui.control.Slider
        UIAxes            matlab.ui.control.UIAxes
    end


    properties (Access = private)
        TestImg1 % Description
        TestImg2 % Description
    end


    methods (Access = private)

        % Button pushed function: LoadImagesButton
        function LoadImagesButtonPushed(app, event)
            currentPath = pwd;
            testImgName1 = 'alphaimg/light11.jpeg';
            testImgName2 = 'alphaimg/dark11.jpeg';
            img1 = imread(fullfile(currentPath, testImgName1));
            imshow(img1, 'Parent', app.UIAxes);
            img2 = imread(fullfile(currentPath, testImgName2));
            imshow(img2, 'Parent', app.UIAxes2);
            fuImg1 = zeros(size(img1));
            fuImg2 = zeros(size(img2));
            fuImg = zeros(size(img2));
            alpha = 0.5;
            for cidx = 1 : 3
                fuImg1(:, :, cidx) = img1(:, :, cidx);
                fuImg2(:, :, cidx) = img2(:, :, cidx);
                fuImg(:, :, cidx) = alpha .* fuImg1(:, :, cidx) + (1 - alpha) .* fuImg2(:, :, cidx);
            end
            imshow(uint8(fuImg), 'Parent', app.UIAxes3);
            app.TestImg1 = fuImg1;
            app.TestImg2 = fuImg2;
        end

        % Value changed function: AlphaSlider
        function AlphaSliderValueChanged(app, event)
            value = app.AlphaSlider.Value;
            alpha = value;
            fuImg = zeros(size(app.TestImg1));
            for cidx = 1 : 3
                fuImg(:, :, cidx) = (1 - alpha) .* app.TestImg1(:, :, cidx) + alpha .* app.TestImg2(:, :, cidx);
            end
            imshow(uint8(fuImg), 'Parent', app.UIAxes3);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.LoadImagesButton = uibutton(app.UIFigure, 'push');
            app.LoadImagesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImagesButtonPushed, true);
            app.LoadImagesButton.Text = 'Load Images';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title');
            xlabel(app.UIAxes2, 'X');
            ylabel(app.UIAxes2, 'Y');
            app.UIAxes2.CLim = [0 1];
            app.UIAxes2.CLimMode = 'manual';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title');
            xlabel(app.UIAxes3, 'X');
            ylabel(app.UIAxes3, 'Y');
            app.AlphaSliderLabel = uilabel(app.UIFigure);
            app.AlphaSliderLabel.Text = 'Alpha';
            app.AlphaSlider = uislider(app.UIFigure);
            app.AlphaSlider.Limits = [0 1];
            app.AlphaSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.AlphaSlider.ValueChangedFcn = createCallbackFcn(app, @AlphaSliderValueChanged, true);
            app.AlphaSlider.Value = 0.5;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
        end
    end

    methods (Access = public)

        % Construct app
        function app = ImgFusionApp()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end